classdef lab2_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        ObstacleButton        matlab.ui.control.Button
        Event1Button          matlab.ui.control.Button
        Event2Button          matlab.ui.control.Button
        Event3Button          matlab.ui.control.Button
        Event4Button          matlab.ui.control.Button
        ExitButton            matlab.ui.control.Button
        SimulateButton        matlab.ui.control.Button
        PerformanceButton     matlab.ui.control.Button
        TimeEditFieldLabel    matlab.ui.control.Label
        TimeEditField         matlab.ui.control.NumericEditField
        EnergyEditFieldLabel  matlab.ui.control.Label
        EnergyEditField       matlab.ui.control.NumericEditField
        LoadmapButton         matlab.ui.control.Button
    end


    properties (Access = private)
        object_num = 1; % Description
        objects = {};
        e1_num = 1;
        e1 = {};
        e2_num = 1;
        e2 = {};
        e2_limit = 20;
        e3_num = 1;
        e3 = {};
        e4_num = 1;
        e4 = {};
        gray_map = [];
        map_size = [512 512];
        map = imresize(imread('ist_gmaps.png'), [512 512]);
        u=0;
        G={};
        x_g=0;
        y_g=0;
        sim_num=0;
        carPose=0;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow(app.map, 'Parent', app.UIAxes);
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            waitfor(msgbox('Welcome. To simulate the car begin by setting the obstacles and events. If you have previously chosen the obstacles you can now Load the map by clicking Load map.'));
        end

        % Button pushed function: ObstacleButton
        function ObstacleButtonPushed(app, event)
            figure(1);
            imshow(app.map);
            hold on;
            hold(app.UIAxes,'on')
            if app.object_num==1
                delete('map_obstacles.mat');
                waitfor(msgbox('Choose the corners of one obstacle (one by one). Right click to finish when an obstacle has been selected.'));
            end
            button = 1;
            [x(1),y(1),button] = ginput(1);
            k = 2;
            while button==1,
                [x(k),y(k),button] = ginput(1);
                if button~=1
                    x = x(1:k-1);
                    y = y(1:k-1);
                    break
                end
                plot(app.UIAxes, [x(k-1) x(k)],[y(k-1) y(k)])
                plot([x(k-1) x(k)],[y(k-1) y(k)]) 
                k = k + 1;               
            end
            app.objects{app.object_num}.x = x;
            app.objects{app.object_num}.y = y;
            app.object_num = app.object_num + 1;
            figHandles = findobj('type', 'figure', '-not', 'figure', 'figure1');
            close(figHandles);         
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            objs = app.objects;
            G = app.G;
            y_g = app.x_g;
            x_g = app.y_g;
            save('map_obstacles.mat','objs','G','x_g','y_g'); 
            closereq;
        end

        % Button pushed function: Event1Button
        function Event1ButtonPushed(app, event)
            figure(1);            
            imshow(app.map);
            hold on;
            hold(app.UIAxes,'on')
            waitfor(msgbox('Choose the locations for the event "stop sign". Right click when finished.'))
            button = 1;
            k = 1;
            while button==1,
                [x(k),y(k),button] = ginput(1);
                if button~=1
                    x = x(1:k-1);
                    y = y(1:k-1);
                    break
                end
                d = imread('stop_sign_image.png');
                image(d, 'XData', [x(k)-10 x(k)+10], 'YData', [y(k)-10 y(k)+10], 'Parent', app.UIAxes);
                image(d, 'XData', [x(k)-10 x(k)+10], 'YData', [y(k)-10 y(k)+10]);
                app.e1{app.e1_num}.x = x(k);
                app.e1{app.e1_num}.y = y(k);
                app.e1_num = app.e1_num + 1;
                k = k + 1;               
            end
            figHandles = findobj('type', 'figure', '-not', 'figure', 'figure1');
            close(figHandles);                 
        end

        % Button pushed function: Event2Button
        function Event2ButtonPushed(app, event)
            figure(1);            
            imshow(app.map);
            hold on;
            hold(app.UIAxes,'on')
            waitfor(msgbox('Choose the locations for the event "speed limit sign". Right click when finished.'))
            button = 1;
            k = 1;
            while button==1,
                [x(k),y(k),button] = ginput(1);
                if button~=1
                    x = x(1:k-1);
                    y = y(1:k-1);
                    break
                end
                prompt = {'Enter the speed limit (0-50):'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'20'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.e2_limit = str2double(answer)/50;
                d = imread('speed_limit_image.png');
                image(d, 'XData', [x(k)-10 x(k)+10], 'YData', [y(k)-10 y(k)+10], 'Parent', app.UIAxes);
                image(d, 'XData', [x(k)-10 x(k)+10], 'YData', [y(k)-10 y(k)+10]);
                app.e2{app.e2_num}.x = x(k);
                app.e2{app.e2_num}.y = y(k);
                app.e2_num = app.e2_num + 1;
                k = k + 1;               
            end
            figHandles = findobj('type', 'figure', '-not', 'figure', 'figure1');
            close(figHandles);              
        end

        % Button pushed function: Event3Button
        function Event3ButtonPushed(app, event)
            figure(1);            
            imshow(app.map);
            hold on;
            hold(app.UIAxes,'on')
            waitfor(msgbox('Choose the locations for the event "pedestrian crossing sign". Right click when finished.'))
            button = 1;
            k = 1;
            while button==1,
                [x(k),y(k),button] = ginput(1);
                if button~=1
                    x = x(1:k-1);
                    y = y(1:k-1);
                    break
                end                
                d = imread('pedestrian_crossing_image.png');
                image(d, 'XData', [x(k)-10 x(k)+10], 'YData', [y(k)-10 y(k)+10], 'Parent', app.UIAxes);
                image(d, 'XData', [x(k)-10 x(k)+10], 'YData', [y(k)-10 y(k)+10]);
                app.e3{app.e3_num}.x = x(k);
                app.e3{app.e3_num}.y = y(k);
                app.e3_num = app.e3_num + 1;
                k = k + 1;               
            end
            figHandles = findobj('type', 'figure', '-not', 'figure', 'figure1');
            close(figHandles);              
        end

        % Button pushed function: Event4Button
        function Event4ButtonPushed(app, event)
            figure(1);            
            imshow(app.map);
            hold on;
            hold(app.UIAxes,'on')
            waitfor(msgbox('Choose the locations for the event "pedestrian crossing". Right click when finished.'))
            button = 1;
            k = 1;
            while button==1,
                [x(k),y(k),button] = ginput(1);
                if button~=1
                    x = x(1:k-1);
                    y = y(1:k-1);
                    break
                end                
                d = imread('person_image.png');
                image(d, 'XData', [x(k)-10 x(k)+10], 'YData', [y(k)-10 y(k)+10], 'Parent', app.UIAxes);
                image(d, 'XData', [x(k)-10 x(k)+10], 'YData', [y(k)-10 y(k)+10]);
                prompt = {'Enter starting time of the event:','Enter duration of the event:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'20','5'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.e4{app.e4_num}.start_time = str2double(answer{1});
                app.e4{app.e4_num}.duration = str2double(answer{2});
                app.e4{app.e4_num}.x = x(k);
                app.e4{app.e4_num}.y = y(k);
                app.e4_num = app.e4_num + 1;
                k = k + 1;               
            end
            figHandles = findobj('type', 'figure', '-not', 'figure', 'figure1');
            close(figHandles);              
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.sim_num = app.sim_num + 1;
            if app.sim_num ~= 1
                map_load = load('map_obstacles.mat');
                app.G = map_load.G;
                app.x_g = map_load.x_g;
                app.y_g = map_load.y_g;
                app.objects = map_load.objs;
                app.object_num = length(map_load.objs);              
                imshow(app.map, 'Parent', app.UIAxes);
                hold(app.UIAxes,'on')
                title(app.UIAxes, []);
                xlabel(app.UIAxes, []);
                ylabel(app.UIAxes, []);
                app.UIAxes.XAxis.TickLabels = {};
                app.UIAxes.YAxis.TickLabels = {};
                for j=1:app.object_num
                    for i=2:length(app.objects{j}.x)
                        plot(app.UIAxes, [app.objects{j}.x(i-1) app.objects{j}.x(i)],[app.objects{j}.y(i-1) app.objects{j}.y(i)]);
                    end
                end
                for i=1:app.e1_num-1
                    d = imread('stop_sign_image.png');
                    image(d, 'XData', [app.e1{i}.x-10 app.e1{i}.x+10], 'YData', [app.e1{i}.y-10 app.e1{i}.y+10], 'Parent', app.UIAxes);
                end

                for i=1:app.e2_num-1
                    d = imread('speed_limit_image.png');
                    image(d, 'XData', [app.e2{i}.x-10 app.e2{i}.x+10], 'YData', [app.e2{i}.y-10 app.e2{i}.y+10], 'Parent', app.UIAxes);
                end

                for i=1:app.e3_num-1
                    d = imread('pedestrian_crossing_image.png');
                    image(d, 'XData', [app.e3{i}.x-10 app.e3{i}.x+10], 'YData', [app.e3{i}.y-10 app.e3{i}.y+10], 'Parent', app.UIAxes);
                end

                for i=1:app.e4_num-1
                    d = imread('person_image.png');
                    image(d, 'XData', [app.e4{i}.x-10 app.e4{i}.x+10], 'YData', [app.e4{i}.y-10 app.e4{i}.y+10], 'Parent', app.UIAxes);
                end
            end
            waitfor(msgbox('To simulate choose the points through which you want the car to pass. The final point is chosen by pressing right click.'))
            [app.G,app.x_g,app.y_g,app.u,app.carPose]=simulation(app.map,app.map_size,app.objects,app.e1,app.e2, app.e2_limit,app.e3,app.e4,app.UIAxes,app.TimeEditField,app.EnergyEditField,app.G,app.x_g,app.y_g);            
        end

        % Button pushed function: PerformanceButton
        function PerformanceButtonPushed(app, event)
            plot(0:length(app.u)-1, app.u);
            figure(2);
            plot(0:length(app.carPose)-1, app.carPose(:,4));
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
        end

        % Value changed function: EnergyEditField
        function EnergyEditFieldValueChanged(app, event)
            value = app.EnergyEditField.Value;           
        end

        % Button pushed function: LoadmapButton
        function LoadmapButtonPushed(app, event)
            map_load = load('map_obstacles.mat');
            app.G = map_load.G;
            app.x_g = map_load.x_g;
            app.y_g = map_load.y_g;
            app.objects = map_load.objs;
            app.object_num = length(map_load.objs);
            imshow(app.map, 'Parent', app.UIAxes);
            hold(app.UIAxes,'on')
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            for j=1:app.object_num
                for i=2:length(app.objects{j}.x)
                    plot(app.UIAxes, [app.objects{j}.x(i-1) app.objects{j}.x(i)],[app.objects{j}.y(i-1) app.objects{j}.y(i)]);
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ObstacleButton = uibutton(app.UIFigure, 'push');
            app.ObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @ObstacleButtonPushed, true);
            app.ObstacleButton.Text = 'Obstacle';
            app.Event1Button = uibutton(app.UIFigure, 'push');
            app.Event1Button.ButtonPushedFcn = createCallbackFcn(app, @Event1ButtonPushed, true);
            app.Event1Button.Text = 'Event1';
            app.Event2Button = uibutton(app.UIFigure, 'push');
            app.Event2Button.ButtonPushedFcn = createCallbackFcn(app, @Event2ButtonPushed, true);
            app.Event2Button.Text = 'Event2';
            app.Event3Button = uibutton(app.UIFigure, 'push');
            app.Event3Button.ButtonPushedFcn = createCallbackFcn(app, @Event3ButtonPushed, true);
            app.Event3Button.Text = 'Event3';
            app.Event4Button = uibutton(app.UIFigure, 'push');
            app.Event4Button.ButtonPushedFcn = createCallbackFcn(app, @Event4ButtonPushed, true);
            app.Event4Button.Text = 'Event4';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.PerformanceButton = uibutton(app.UIFigure, 'push');
            app.PerformanceButton.ButtonPushedFcn = createCallbackFcn(app, @PerformanceButtonPushed, true);
            app.PerformanceButton.Text = 'Performance';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.EnergyEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergyEditField.ValueChangedFcn = createCallbackFcn(app, @EnergyEditFieldValueChanged, true);
            app.LoadmapButton = uibutton(app.UIFigure, 'push');
            app.LoadmapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmapButtonPushed, true);
            app.LoadmapButton.Text = 'Load map';
        end
    end

    methods (Access = public)

        % Construct app
        function app = lab2_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end