classdef lab2UIApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        ExitButton              matlab.ui.control.Button
        SimulateButton          matlab.ui.control.Button
        ResultsButton           matlab.ui.control.Button
        TimeEditFieldLabel      matlab.ui.control.Label
        TimeEditField           matlab.ui.control.NumericEditField
        EnergyEditFieldLabel    matlab.ui.control.Label
        EnergyEditField         matlab.ui.control.NumericEditField
        SaveButton              matlab.ui.control.Button
        VelocityEditFieldLabel  matlab.ui.control.Label
        VelocityEditField       matlab.ui.control.NumericEditField
        InstructionsButton      matlab.ui.control.Button
        EventsButtonGroup       matlab.ui.container.ButtonGroup
        E1Button                matlab.ui.control.Button
        E2Button                matlab.ui.control.Button
        E3Button                matlab.ui.control.Button
        E4Button                matlab.ui.control.Button
        ObstaclesButtonGroup    matlab.ui.container.ButtonGroup
        ObstacleButton          matlab.ui.control.Button
        LoadobstaclesButton     matlab.ui.control.Button
        orLabel                 matlab.ui.control.Label
        DistanceEditFieldLabel  matlab.ui.control.Label
        DistanceEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        object_num = 1; % Description
        objects = {};
        e1_num = 1;
        e1 = {};
        e2_num = 1;
        e2 = {};
        e2_limit = 20;
        e3_num = 1;
        e3 = {};
        e4_num = 1;
        e4 = {};
        gray_map = [];
        map_size = [512 512];
        map = imresize(imread('ist_gmaps.png'), [512 512]);
        u=0;
        G={};
        x_g=0;
        y_g=0;
        sim_num=0;
        carPose=0;
        e=0;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow(app.map, 'Parent', app.UIAxes);
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            msgbox('Welcome. To simulate the car begin by setting the obstacles and events.');
        end

        % Button pushed function: ObstacleButton
        function ObstacleButtonPushed(app, event)
            figure(1);
            imshow(app.map);
            hold on;
            hold(app.UIAxes,'on')
            if app.object_num==1
                delete('map_obstacles.mat');
                waitfor(msgbox('Choose the corners of one obstacle (one by one). Right click to finish when an obstacle has been selected.'));
            end
            button = 1;
            [x(1),y(1),button] = ginput(1);
            k = 2;
            while button==1,
                [x(k),y(k),button] = ginput(1);
                if button~=1
                    x = x(1:k-1);
                    y = y(1:k-1);
                    break
                end
                plot(app.UIAxes, [x(k-1) x(k)],[y(k-1) y(k)])
                plot([x(k-1) x(k)],[y(k-1) y(k)]) 
                k = k + 1;               
            end
            app.objects{app.object_num}.x = x;
            app.objects{app.object_num}.y = y;
            app.object_num = app.object_num + 1;
            figHandles = findobj('type', 'figure', '-not', 'figure', 'figure1');
            close(figHandles);  
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: E1Button
        function E1ButtonPushed(app, event)
            figure(1);            
            imshow(app.map);
            hold on;
            hold(app.UIAxes,'on')
            waitfor(msgbox('Choose the locations for the event "stop sign". Right click when finished.'))
            button = 1;
            k = 1;
            while button==1,
                [x(k),y(k),button] = ginput(1);
                if button~=1
                    x = x(1:k-1);
                    y = y(1:k-1);
                    break
                end
                d = imread('stop_sign_image.png');
                img_s_x = 5;
                img_s_y = 5;
                image(d, 'XData', [x(k)-img_s_x x(k)+img_s_x], 'YData', [y(k)-img_s_y y(k)+img_s_y], 'Parent', app.UIAxes);
                image(d, 'XData', [x(k)-img_s_x x(k)+img_s_x], 'YData', [y(k)-img_s_y y(k)+img_s_y]);
                app.e1{app.e1_num}.x = x(k);
                app.e1{app.e1_num}.y = y(k);
                app.e1_num = app.e1_num + 1;
                k = k + 1;               
            end
            figHandles = findobj('type', 'figure', '-not', 'figure', 'figure1');
            close(figHandles); 
        end

        % Button pushed function: E2Button
        function E2ButtonPushed(app, event)
            figure(1);            
            imshow(app.map);
            hold on;
            hold(app.UIAxes,'on')
            waitfor(msgbox('Choose the locations for the event "speed limit sign". Right click when finished.'))
            button = 1;
            k = 1;
            while button==1,
                [x(k),y(k),button] = ginput(1);
                if button~=1
                    x = x(1:k-1);
                    y = y(1:k-1);
                    break
                end
                prompt = {'Enter the speed limit (0-50):'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'20'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.e2_limit = str2double(answer)/50;
                d = imread('speed_limit_image.png');
                img_s_x = 5;
                img_s_y = 5;
                image(d, 'XData', [x(k)-img_s_x x(k)+img_s_x], 'YData', [y(k)-img_s_y y(k)+img_s_y], 'Parent', app.UIAxes);
                image(d, 'XData', [x(k)-img_s_x x(k)+img_s_x], 'YData', [y(k)-img_s_y y(k)+img_s_y]);
                app.e2{app.e2_num}.x = x(k);
                app.e2{app.e2_num}.y = y(k);
                app.e2_num = app.e2_num + 1;
                k = k + 1;               
            end
            figHandles = findobj('type', 'figure', '-not', 'figure', 'figure1');
            close(figHandles);           
        end

        % Button pushed function: E3Button
        function Event3ButtonPushed(app, event)
            figure(1);            
            imshow(app.map);
            hold on;
            hold(app.UIAxes,'on')
            waitfor(msgbox('Choose the locations for the event "pedestrian crossing sign". Right click when finished.'))
            button = 1;
            k = 1;
            while button==1,
                [x(k),y(k),button] = ginput(1);
                if button~=1
                    x = x(1:k-1);
                    y = y(1:k-1);
                    break
                end                
                d = imread('pedestrian_crossing_image.png');
                img_s_x = 5;
                img_s_y = 5;
                image(d, 'XData', [x(k)-img_s_x x(k)+img_s_x], 'YData', [y(k)-img_s_y y(k)+img_s_y], 'Parent', app.UIAxes);
                image(d, 'XData', [x(k)-img_s_x x(k)+img_s_x], 'YData', [y(k)-img_s_y y(k)+img_s_y]);
                app.e3{app.e3_num}.x = x(k);
                app.e3{app.e3_num}.y = y(k);
                app.e3_num = app.e3_num + 1;
                k = k + 1;               
            end
            figHandles = findobj('type', 'figure', '-not', 'figure', 'figure1');
            close(figHandles);            
        end

        % Button pushed function: E4Button
        function Event4ButtonPushed(app, event)
            figure(1);            
            imshow(app.map);
            hold on;
            hold(app.UIAxes,'on')
            waitfor(msgbox('Choose the locations for the event "pedestrian crossing". Right click when finished.'))
            button = 1;
            k = 1;
            while button==1,
                [x(k),y(k),button] = ginput(1);
                if button~=1
                    x = x(1:k-1);
                    y = y(1:k-1);
                    break
                end                
                d = imread('person_image.png');
                img_s_x = 5;
                img_s_y = 5;
                image(d, 'XData', [x(k)-img_s_x x(k)+img_s_x], 'YData', [y(k)-img_s_y y(k)+img_s_y], 'Parent', app.UIAxes);
                image(d, 'XData', [x(k)-img_s_x x(k)+img_s_x], 'YData', [y(k)-img_s_y y(k)+img_s_y]);
                prompt = {'Enter starting time of the event:','Enter duration of the event:'};
                dlgtitle = 'Input';
                dims = [1 35];
                definput = {'20','5'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.e4{app.e4_num}.start_time = str2double(answer{1});
                app.e4{app.e4_num}.duration = str2double(answer{2});
                app.e4{app.e4_num}.x = x(k);
                app.e4{app.e4_num}.y = y(k);
                app.e4_num = app.e4_num + 1;
                k = k + 1;               
            end
            figHandles = findobj('type', 'figure', '-not', 'figure', 'figure1');
            close(figHandles);       
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.sim_num = app.sim_num + 1;
            if app.sim_num ~= 1
                map_load = load('map_obstacles.mat');
                app.G = map_load.G;
                app.x_g = map_load.x_g;
                app.y_g = map_load.y_g;
                app.objects = map_load.objs;
                app.object_num = length(map_load.objs);              
                imshow(app.map, 'Parent', app.UIAxes);
                hold(app.UIAxes,'on')
                title(app.UIAxes, []);
                xlabel(app.UIAxes, []);
                ylabel(app.UIAxes, []);
                app.UIAxes.XAxis.TickLabels = {};
                app.UIAxes.YAxis.TickLabels = {};
                img_s_x = 5;
                img_s_y = 5;
                for j=1:app.object_num
                    for i=2:length(app.objects{j}.x)
                        plot(app.UIAxes, [app.objects{j}.x(i-1) app.objects{j}.x(i)],[app.objects{j}.y(i-1) app.objects{j}.y(i)]);
                    end
                end
                for i=1:app.e1_num-1
                    d = imread('stop_sign_image.png');
                    image(d, 'XData', [app.e1{i}.x-img_s_x app.e1{i}.x+img_s_x], 'YData', [app.e1{i}.y-img_s_y app.e1{i}.y+img_s_y], 'Parent', app.UIAxes);
                end

                for i=1:app.e2_num-1
                    d = imread('speed_limit_image.png');
                    image(d, 'XData', [app.e2{i}.x-img_s_x app.e2{i}.x+img_s_x], 'YData', [app.e2{i}.y-img_s_y app.e2{i}.y+img_s_y], 'Parent', app.UIAxes);
                end

                for i=1:app.e3_num-1
                    d = imread('pedestrian_crossing_image.png');
                    image(d, 'XData', [app.e3{i}.x-img_s_x app.e3{i}.x+img_s_x], 'YData', [app.e3{i}.y-img_s_y app.e3{i}.y+img_s_y], 'Parent', app.UIAxes);
                end

                for i=1:app.e4_num-1
                    d = imread('person_image.png');
                    image(d, 'XData', [app.e4{i}.x-img_s_x app.e4{i}.x+img_s_x], 'YData', [app.e4{i}.y-img_s_y app.e4{i}.y+img_s_y], 'Parent', app.UIAxes);
                end
            end
            waitfor(msgbox('To simulate choose the points through which you want the car to pass. The final point is chosen by pressing right click. It may take a while on the first run.'))
            [app.G,app.x_g,app.y_g,app.u,app.carPose, app.e]=simulation(app.map,app.map_size,app.objects,app.e1,app.e2, app.e2_limit,app.e3,app.e4,app.UIAxes,app.TimeEditField,app.EnergyEditField, app.VelocityEditField,app.DistanceEditField, app.G,app.x_g,app.y_g);              
        end

        % Button pushed function: ResultsButton
        function ResultsButtonPushed(app, event)
            %Plot controller outputs
            h = 0.01;
            time = (0:length(app.u)-1)*h;
            v = app.u(1,:);
            ws = app.u(2,:);
            cont_out = figure;
            yyaxis right
            plot(time,ws);
            ylabel('\omega_s (rad/s)');
            hold on
            yyaxis left
            plot(time,v);
            hold off
            title('Controller outputs');
            xlabel('Time (s)');
            ylabel('v (m/s)');
            %Plot look ahead errors
            e_dis = app.e(1,:);
            e_ang = app.e(2,:);
            errors = figure;
            yyaxis left
            plot(time,e_dis);
            ylabel('Error distance (m)');
            hold on
            yyaxis right
            plot(time,e_ang);
            title('Look ahead error');
            xlabel('Time (s)');
            ylabel('Error orientation (rad)');
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
        end

        % Value changed function: EnergyEditField
        function EnergyEditFieldValueChanged(app, event)
            value = app.EnergyEditField.Value;           
        end

        % Button pushed function: LoadobstaclesButton
        function LoadobstaclesButtonPushed(app, event)
            map_load = load('map_obstacles.mat');
            app.G = map_load.G;
            app.x_g = map_load.x_g;
            app.y_g = map_load.y_g;
            app.objects = map_load.objs;
            app.object_num = length(map_load.objs);
            imshow(app.map, 'Parent', app.UIAxes);
            hold(app.UIAxes,'on')
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            for j=1:app.object_num
                for i=2:length(app.objects{j}.x)
                    plot(app.UIAxes, [app.objects{j}.x(i-1) app.objects{j}.x(i)],[app.objects{j}.y(i-1) app.objects{j}.y(i)]);
                end
            end
        end

        % Button pushed function: InstructionsButton
        function InstructionsButtonPushed(app, event)
            open('ReadMe.txt');
            %Add instruction pop-up
        end

        % Value changed function: VelocityEditField
        function VelocityEditFieldValueChanged(app, event)
            value = app.VelocityEditField.Value;
        end

        % Value changed function: DistanceEditField
        function DistanceEditFieldValueChanged(app, event)
            value = app.DistanceEditField.Value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            objs = app.objects;
            G = app.G;
            y_g = app.x_g;
            x_g = app.y_g;
            save('map_obstacles.mat','objs','G','x_g','y_g');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Icon = 'play.png';
            app.SimulateButton.Text = 'Simulate';
            app.ResultsButton = uibutton(app.UIFigure, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsButtonPushed, true);
            app.ResultsButton.Icon = 'graph.png';
            app.ResultsButton.Text = 'Results';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.EnergyEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergyEditField.ValueChangedFcn = createCallbackFcn(app, @EnergyEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityEditFieldValueChanged, true);
            app.InstructionsButton = uibutton(app.UIFigure, 'push');
            app.InstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @InstructionsButtonPushed, true);
            app.InstructionsButton.Text = 'Instructions';
            app.EventsButtonGroup = uibuttongroup(app.UIFigure);
            app.EventsButtonGroup.Title = 'Events';
            app.E1Button = uibutton(app.EventsButtonGroup, 'push');
            app.E1Button.ButtonPushedFcn = createCallbackFcn(app, @E1ButtonPushed, true);
            app.E1Button.Icon = 'stop_sign_image.png';
            app.E1Button.Text = 'E1';
            app.E2Button = uibutton(app.EventsButtonGroup, 'push');
            app.E2Button.ButtonPushedFcn = createCallbackFcn(app, @E2ButtonPushed, true);
            app.E2Button.Icon = 'speed_limit_image.png';
            app.E2Button.Text = 'E2';
            app.E3Button = uibutton(app.EventsButtonGroup, 'push');
            app.E3Button.ButtonPushedFcn = createCallbackFcn(app, @Event3ButtonPushed, true);
            app.E3Button.Icon = 'pedestrian_crossing_image.png';
            app.E3Button.Text = 'E3';
            app.E4Button = uibutton(app.EventsButtonGroup, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @Event4ButtonPushed, true);
            app.E4Button.Icon = 'person_image.png';
            app.E4Button.Text = 'E4';
            app.ObstaclesButtonGroup = uibuttongroup(app.UIFigure);
            app.ObstaclesButtonGroup.Title = 'Obstacles';
            app.ObstacleButton = uibutton(app.ObstaclesButtonGroup, 'push');
            app.ObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @ObstacleButtonPushed, true);
            app.ObstacleButton.Text = 'Obstacle';
            app.LoadobstaclesButton = uibutton(app.ObstaclesButtonGroup, 'push');
            app.LoadobstaclesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadobstaclesButtonPushed, true);
            app.LoadobstaclesButton.Text = 'Load obstacles';
            app.orLabel = uilabel(app.ObstaclesButtonGroup);
            app.orLabel.Text = 'or';
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = lab2UIApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end